classdef LabAssignment2GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        EMERGENCYSTOPButton       matlab.ui.control.Button
        Label_7                   matlab.ui.control.Label
        SENDPOSITIONButton_2      matlab.ui.control.Button
        SENDPOSITIONButton        matlab.ui.control.Button
        IDLEButton                matlab.ui.control.Button
        Label_6                   matlab.ui.control.Label
        WAKEButton                matlab.ui.control.Button
        Label_5                   matlab.ui.control.Label
        SLEEPButton               matlab.ui.control.Button
        Label_4                   matlab.ui.control.Label
        RESETButton               matlab.ui.control.Button
        Label_3                   matlab.ui.control.Label
        ChargePositionSetupLabel  matlab.ui.control.Label
        UsePositionSetupLabel     matlab.ui.control.Label
        XPositionSlider_2         matlab.ui.control.Slider
        XPositionSlider_2Label    matlab.ui.control.Label
        YPositionSlider_2         matlab.ui.control.Slider
        YPositionSlider_2Label    matlab.ui.control.Label
        ZPositionSlider_2         matlab.ui.control.Slider
        ZPositionSlider_2Label    matlab.ui.control.Label
        PitchSlider               matlab.ui.control.Slider
        PitchSliderLabel          matlab.ui.control.Label
        ZPositionSlider           matlab.ui.control.Slider
        ZPositionSliderLabel      matlab.ui.control.Label
        YPositionSlider           matlab.ui.control.Slider
        YPositionSliderLabel      matlab.ui.control.Label
        XPositionSlider           matlab.ui.control.Slider
        XPositionSliderLabel      matlab.ui.control.Label
        STARTButton               matlab.ui.control.Button
        Label_2                   matlab.ui.control.Label
    end

    
    properties (Access = private)
        lab2;                                                               % Handler for the LabAssignment2() class
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.lab2 = LabAssignment2();                                    % Create an instance of LabAssignment2() on startup
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            app.lab2.setIndex(1);                                           % Set the index to '1' --> Start
        end

        % Value changed function: XPositionSlider
        function XPositionSliderValueChanged(app, event)
            % value = app.XPositionSlider.Value;
            % app.useX = value;
        end

        % Value changed function: YPositionSlider
        function YPositionSliderValueChanged(app, event)
            % value = app.YPositionSlider.Value;
            % app.useY = value;
        end

        % Value changed function: ZPositionSlider
        function ZPositionSliderValueChanged(app, event)
            % value = app.ZPositionSlider.Value;
            % app.useZ = value;
        end

        % Value changed function: PitchSlider
        function PitchSliderValueChanged(app, event)
            % value = app.PitchSlider.Value;
            % app.useP = value;
        end

        % Value changed function: XPositionSlider_2
        function XPositionSlider_2ValueChanged(app, event)
            % value = app.XPositionSlider_2.Value;
            % app.chargeX = value;
        end

        % Value changed function: YPositionSlider_2
        function YPositionSlider_2ValueChanged(app, event)
            % value = app.YPositionSlider_2.Value;
            % app.chargeY = value;
        end

        % Value changed function: ZPositionSlider_2
        function ZPositionSlider_2ValueChanged(app, event)
            % value = app.ZPositionSlider_2.Value;
            % app.chargeZ = value;
        end

        % Button pushed function: SENDPOSITIONButton
        function SENDPOSITIONButtonPushed(app, event)
            % transl = [app.useX,app.useY,app.useZ];
            % rot = rotx(pi)*roty(app.useP)*rotz(-pi/2);
            % transf = SE3(rot,transl);
            % 
            % app.lab2.useCall(transf);
        end

        % Button pushed function: SENDPOSITIONButton_2
        function SENDPOSITIONButton_2Pushed(app, event)
            % transl = [app.chargeX,app.chargeY,app.chargeZ];
            % rot = rotx(-pi/2)*rotz(0);
            % transf = SE3(rot,transl);
            % 
            % app.lab2.chargeCall(transf);
        end

        % Button pushed function: SLEEPButton
        function SLEEPButtonPushed(app, event)
            app.lab2.setIndex(2);                                           % Set the index to '2' --> Sleep
        end

        % Button pushed function: WAKEButton
        function WAKEButtonPushed(app, event)
            app.lab2.setIndex(3);                                           % Set the index to '3' --> Wake
        end

        % Button pushed function: IDLEButton
        function IDLEButtonPushed(app, event)
            app.lab2.setIndex(4);                                           % Set the index to '4' --> Idle
        end

        % Button pushed function: EMERGENCYSTOPButton
        function EMERGENCYSTOPButtonPushed(app, event)
            app.lab2.setIndex(5);                                           % Set the index to '5' --> E-Stop
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            app.lab2.setIndex(6);                                           % Set the index to '6' --> Reset
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Tag = 'pb.Start';
            app.STARTButton.Text = 'START';
            app.XPositionSliderLabel = uilabel(app.UIFigure);
            app.XPositionSliderLabel.Enable = 'off';
            app.XPositionSliderLabel.Visible = 'off';
            app.XPositionSliderLabel.Text = 'X Position';
            app.XPositionSlider = uislider(app.UIFigure);
            app.XPositionSlider.MajorTicks = [0 20 40 60 80 100];
            app.XPositionSlider.MajorTickLabels = {''};
            app.XPositionSlider.ValueChangedFcn = createCallbackFcn(app, @XPositionSliderValueChanged, true);
            app.XPositionSlider.MinorTicks = [];
            app.XPositionSlider.Tag = 'usePos.x';
            app.XPositionSlider.Enable = 'off';
            app.XPositionSlider.Visible = 'off';
            app.YPositionSliderLabel = uilabel(app.UIFigure);
            app.YPositionSliderLabel.Enable = 'off';
            app.YPositionSliderLabel.Visible = 'off';
            app.YPositionSliderLabel.Text = 'Y Position';
            app.YPositionSlider = uislider(app.UIFigure);
            app.YPositionSlider.MajorTicks = [0 20 40 60 80 100];
            app.YPositionSlider.MajorTickLabels = {''};
            app.YPositionSlider.ValueChangedFcn = createCallbackFcn(app, @YPositionSliderValueChanged, true);
            app.YPositionSlider.MinorTicks = [];
            app.YPositionSlider.Tag = 'usePos.y';
            app.YPositionSlider.Enable = 'off';
            app.YPositionSlider.Visible = 'off';
            app.ZPositionSliderLabel = uilabel(app.UIFigure);
            app.ZPositionSliderLabel.Enable = 'off';
            app.ZPositionSliderLabel.Visible = 'off';
            app.ZPositionSliderLabel.Text = 'Z Position';
            app.ZPositionSlider = uislider(app.UIFigure);
            app.ZPositionSlider.MajorTicks = [0 20 40 60 80 100];
            app.ZPositionSlider.MajorTickLabels = {''};
            app.ZPositionSlider.ValueChangedFcn = createCallbackFcn(app, @ZPositionSliderValueChanged, true);
            app.ZPositionSlider.MinorTicks = [];
            app.ZPositionSlider.Tag = 'usePos.z';
            app.ZPositionSlider.Enable = 'off';
            app.ZPositionSlider.Visible = 'off';
            app.PitchSliderLabel = uilabel(app.UIFigure);
            app.PitchSliderLabel.Enable = 'off';
            app.PitchSliderLabel.Visible = 'off';
            app.PitchSliderLabel.Text = 'Pitch';
            app.PitchSlider = uislider(app.UIFigure);
            app.PitchSlider.MajorTicks = [0 20 40 60 80 100];
            app.PitchSlider.MajorTickLabels = {''};
            app.PitchSlider.ValueChangedFcn = createCallbackFcn(app, @PitchSliderValueChanged, true);
            app.PitchSlider.MinorTicks = [];
            app.PitchSlider.Tag = 'usePos.p';
            app.PitchSlider.Enable = 'off';
            app.PitchSlider.Visible = 'off';
            app.ZPositionSlider_2Label = uilabel(app.UIFigure);
            app.ZPositionSlider_2Label.Enable = 'off';
            app.ZPositionSlider_2Label.Visible = 'off';
            app.ZPositionSlider_2Label.Text = 'Z Position';
            app.ZPositionSlider_2 = uislider(app.UIFigure);
            app.ZPositionSlider_2.MajorTicks = [0 20 40 60 80 100];
            app.ZPositionSlider_2.MajorTickLabels = {''};
            app.ZPositionSlider_2.ValueChangedFcn = createCallbackFcn(app, @ZPositionSlider_2ValueChanged, true);
            app.ZPositionSlider_2.MinorTicks = [];
            app.ZPositionSlider_2.Tag = 'chargePos.z';
            app.ZPositionSlider_2.Enable = 'off';
            app.ZPositionSlider_2.Visible = 'off';
            app.YPositionSlider_2Label = uilabel(app.UIFigure);
            app.YPositionSlider_2Label.Enable = 'off';
            app.YPositionSlider_2Label.Visible = 'off';
            app.YPositionSlider_2Label.Text = 'Y Position';
            app.YPositionSlider_2 = uislider(app.UIFigure);
            app.YPositionSlider_2.MajorTicks = [0 20 40 60 80 100];
            app.YPositionSlider_2.MajorTickLabels = {''};
            app.YPositionSlider_2.ValueChangedFcn = createCallbackFcn(app, @YPositionSlider_2ValueChanged, true);
            app.YPositionSlider_2.MinorTicks = [];
            app.YPositionSlider_2.Tag = 'chargePos.y';
            app.YPositionSlider_2.Enable = 'off';
            app.YPositionSlider_2.Visible = 'off';
            app.XPositionSlider_2Label = uilabel(app.UIFigure);
            app.XPositionSlider_2Label.Enable = 'off';
            app.XPositionSlider_2Label.Visible = 'off';
            app.XPositionSlider_2Label.Text = 'X Position';
            app.XPositionSlider_2 = uislider(app.UIFigure);
            app.XPositionSlider_2.MajorTicks = [0 20 40 60 80 100];
            app.XPositionSlider_2.MajorTickLabels = {''};
            app.XPositionSlider_2.ValueChangedFcn = createCallbackFcn(app, @XPositionSlider_2ValueChanged, true);
            app.XPositionSlider_2.MinorTicks = [];
            app.XPositionSlider_2.Tag = 'chargePos.x';
            app.XPositionSlider_2.Enable = 'off';
            app.XPositionSlider_2.Visible = 'off';
            app.UsePositionSetupLabel = uilabel(app.UIFigure);
            app.UsePositionSetupLabel.Enable = 'off';
            app.UsePositionSetupLabel.Visible = 'off';
            app.UsePositionSetupLabel.Text = 'Use Position Setup';
            app.ChargePositionSetupLabel = uilabel(app.UIFigure);
            app.ChargePositionSetupLabel.Enable = 'off';
            app.ChargePositionSetupLabel.Visible = 'off';
            app.ChargePositionSetupLabel.Text = 'Charge Position Setup';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.RESETButton = uibutton(app.UIFigure, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Tag = 'pb.Reset';
            app.RESETButton.Text = 'RESET';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '';
            app.SLEEPButton = uibutton(app.UIFigure, 'push');
            app.SLEEPButton.ButtonPushedFcn = createCallbackFcn(app, @SLEEPButtonPushed, true);
            app.SLEEPButton.Tag = 'pb.Sleep';
            app.SLEEPButton.Text = 'SLEEP';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '';
            app.WAKEButton = uibutton(app.UIFigure, 'push');
            app.WAKEButton.ButtonPushedFcn = createCallbackFcn(app, @WAKEButtonPushed, true);
            app.WAKEButton.Tag = 'pb.Wake';
            app.WAKEButton.Text = 'WAKE';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '';
            app.IDLEButton = uibutton(app.UIFigure, 'push');
            app.IDLEButton.ButtonPushedFcn = createCallbackFcn(app, @IDLEButtonPushed, true);
            app.IDLEButton.Tag = 'pb.Idle';
            app.IDLEButton.Text = 'IDLE';
            app.SENDPOSITIONButton = uibutton(app.UIFigure, 'push');
            app.SENDPOSITIONButton.ButtonPushedFcn = createCallbackFcn(app, @SENDPOSITIONButtonPushed, true);
            app.SENDPOSITIONButton.Tag = 'pb.sendUse';
            app.SENDPOSITIONButton.Enable = 'off';
            app.SENDPOSITIONButton.Visible = 'off';
            app.SENDPOSITIONButton.Text = 'SEND POSITION';
            app.SENDPOSITIONButton_2 = uibutton(app.UIFigure, 'push');
            app.SENDPOSITIONButton_2.ButtonPushedFcn = createCallbackFcn(app, @SENDPOSITIONButton_2Pushed, true);
            app.SENDPOSITIONButton_2.Tag = 'pb.sendCharge';
            app.SENDPOSITIONButton_2.Enable = 'off';
            app.SENDPOSITIONButton_2.Visible = 'off';
            app.SENDPOSITIONButton_2.Text = 'SEND POSITION';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '';
            app.EMERGENCYSTOPButton = uibutton(app.UIFigure, 'push');
            app.EMERGENCYSTOPButton.ButtonPushedFcn = createCallbackFcn(app, @EMERGENCYSTOPButtonPushed, true);
            app.EMERGENCYSTOPButton.Tag = 'pb.Start';
            app.EMERGENCYSTOPButton.Text = 'EMERGENCY STOP';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LabAssignment2GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end